classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        CurrentvolumeEditFieldLabel  matlab.ui.control.Label
        CurrentvolumeEditField       matlab.ui.control.NumericEditField
        AddcalibrationpointButton    matlab.ui.control.Button
        ReadpointsfromfileButton     matlab.ui.control.Button
        DatapointsListBoxLabel       matlab.ui.control.Label
        DatapointsListBox            matlab.ui.control.ListBox
        DeletepointButton            matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        GaugeLabel                   matlab.ui.control.Label
        Gauge                        matlab.ui.control.LinearGauge
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        % audioReader = audioDeviceReader(44100, 1024);
        updateSoundGraphTimer       timer
    end
    
    methods (Access = private)
        
        function updateSoundGraph(app)
            fs = 44100;
            N_s = 1024; 
            r = audioDeviceReader(fs, N_s);
            data = r();
            % data = [1, 2, 3, 3];
            plot(app.UIAxes2, data(1:N_s));
            app.UIAxes2.YLim = [-1.5, 1.5];
            app.UIAxes2.XLim = [0, N_s];
            
            signal_fft = fft(data);
            freq = 0:(fs / N_s):(fs / 2 - fs / N_s);
            
            plot(app.UIAxes, freq, 2 * abs(signal_fft(1:N_s / 2)));
                
            app.UIAxes.XLim = [0, fs / 2];
            app.UIAxes.YLim = [-1, 50];


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Setup audioreader
            % app.audioReader = audioDeviceReader(44100, 1024);


            % Setup plot callback timer
            app.updateSoundGraphTimer = timer('StartDelay', 3, 'Period', 100e-3, 'TasksToExecute', Inf, 'ExecutionMode', 'fixedRate');
            app.updateSoundGraphTimer.TimerFcn = @(obj,event) updateSoundGraph(app);
            app.updateSoundGraphTimer.start;

        end

        % Button pushed function: DeletepointButton
        function DeletepointButtonPushed(app, event)
            [~,idx] = ismember(app.DatapointsListBox.Value, app.DatapointsListBox.Items);
            app.DatapointsListBox.Items(idx) = [];
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.updateSoundGraphTimer);
            delete(app)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {731, 731};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {250, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CurrentvolumeEditFieldLabel = uilabel(app.LeftPanel);
            app.CurrentvolumeEditFieldLabel.Text = 'Current volume';
            app.CurrentvolumeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AddcalibrationpointButton = uibutton(app.LeftPanel, 'push');
            app.AddcalibrationpointButton.Text = 'Add calibration point';
            app.ReadpointsfromfileButton = uibutton(app.LeftPanel, 'push');
            app.ReadpointsfromfileButton.Text = 'Read points from file';
            app.DatapointsListBoxLabel = uilabel(app.LeftPanel);
            app.DatapointsListBoxLabel.Text = 'Data points';
            app.DatapointsListBox = uilistbox(app.LeftPanel);
            app.DeletepointButton = uibutton(app.LeftPanel, 'push');
            app.DeletepointButton.ButtonPushedFcn = createCallbackFcn(app, @DeletepointButtonPushed, true);
            app.DeletepointButton.Text = 'Delete point';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.GaugeLabel = uilabel(app.GridLayout2);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.GridLayout2, 'linear');
            app.UIAxes2 = uiaxes(app.GridLayout2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end